classdef ViconExtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExperimentSpecificEventEditField  matlab.ui.control.EditField
        ExperimentSpecificEventEditFieldLabel  matlab.ui.control.Label
        ViconLamp                       matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        ConnecttoViconButton            matlab.ui.control.Button
        CreatorEmmaReznickUpdated022023Label  matlab.ui.control.Label
        ViconDataExtractionforFRBLabel  matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        SelectDataTypesLabel            matlab.ui.control.Label
        SelectSaveTargetButton          matlab.ui.control.Button
        SelectTrialsButton              matlab.ui.control.Button
        MakeStructureButton             matlab.ui.control.Button
        StructureNameEditField          matlab.ui.control.EditField
        StructureNameLabel              matlab.ui.control.Label
        DataTypesPanel                  matlab.ui.container.Panel
        SubjectDetailsCheckBox          matlab.ui.control.CheckBox
        EventsCheckBox                  matlab.ui.control.CheckBox
        JointPowersCheckBox             matlab.ui.control.CheckBox
        JointForceCheckBox              matlab.ui.control.CheckBox
        JointMomentCheckBox             matlab.ui.control.CheckBox
        JointVelocitiesCheckBox         matlab.ui.control.CheckBox
        JointAnglesCheckBox             matlab.ui.control.CheckBox
        MarkersCheckBox                 matlab.ui.control.CheckBox
        ForcePlateCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = private)
        trial %trial names
        data_path % data location
        target_path %save path
        vicon
        viconbool = false
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectTrialsButton
        function SelectTrialsButtonPushed(app, event)
            [app.trial,app.data_path] = uigetfile('*.x1d',...
                'Select One or More Files', ...
                'MultiSelect', 'on');
        end

        % Button pushed function: MakeStructureButton
        function MakeStructure(app, event)
            clc
            %toss in some warnings
            if isempty(app.trial)
                warning('No Trials Selected')
            end
            if isempty(app.StructureNameEditField.Value)
                warning('No Structure Name Chosen')
            end
            if isempty(app.target_path)
                warning('No Target Path Selected')
            end
            if ~app.viconbool
                warning('Not Connected to Vicon')
            end
            if isempty(app.trial)||  isempty(app.StructureNameEditField.Value) || isempty(app.target_path) || ~app.viconbool
                app.StatusLamp.Color = 'r';
                return
            end
            app.StatusLamp.Color = 'y';
            
            % initialize bools
            bool_marker = false;
            bool_FP = false;
            bool_Jangle = false;
            bool_Jvel = false;
            bool_Jmom = false;
            bool_Jpow = false;
            bool_Jforce = false;
            bool_event = false;
            bool_subDet = false;
            %             bool_strideTime = false;
            
            
            %collect checked boxes and mark bool appropriately
            if app.MarkersCheckBox.Value
                bool_marker = true;
            end
            if app.ForcePlateCheckBox.Value
                bool_FP = true;
            end
            if app.JointAnglesCheckBox.Value
                bool_Jangle = true;
            end
            if app.JointVelocitiesCheckBox.Value
                bool_Jvel = true;
            end
            if app.JointMomentCheckBox.Value
                bool_Jmom = true;
            end
            if app.JointPowersCheckBox.Value
                bool_Jpow = true;
            end
            if app.JointForceCheckBox.Value
                bool_Jforce = true;
            end
            if app.EventsCheckBox.Value
                bool_event = true;
            end
            if app.SubjectDetailsCheckBox.Value
                bool_subDet = true;
            end
                %                 if contains(checkedBoxes(data_select).Text, 'Stride Time')
                %                     bool_strideTime = true;
                %                 end
           

            try
                ViconExtractionLEADER(app.vicon, app.StructureNameEditField.Value, app.trial, app.data_path, app.target_path, bool_marker, bool_FP, bool_Jangle,...
                    bool_Jvel, bool_Jmom, bool_Jpow, bool_Jforce, bool_event, bool_subDet, app.ExperimentSpecificEventEditField.Value);
                app.StatusLamp.Color = 'g';
            catch
                app.StatusLamp.Color = 'r';
            end

        end

        % Callback function
        function StructureNameEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: SelectSaveTargetButton
        function SelectSaveTargetButtonPushed(app, event)
            app.target_path = uigetdir;
        end

        % Button pushed function: ConnecttoViconButton
        function ConnecttoViconButtonPushed(app, event)
            %% Connect to Vicon Nexus
            try
            addpath('C:\Program Files (x86)\Vicon\Nexus2.12\SDK\Matlab')
            addpath('C:\Program Files (x86)\Vicon\Nexus2.12\SDK\Win64')
            app.vicon = ViconNexus;
            app.viconbool = true;
            app.ViconLamp.Color = 'g';
            catch
                app.ViconLamp.Color = 'r';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataTypesPanel = uipanel(app.UIFigure);
            app.DataTypesPanel.Title = 'Data Types';
            app.ForcePlateCheckBox = uicheckbox(app.DataTypesPanel);
            app.ForcePlateCheckBox.Text = {'Force Plate'; ''};
            app.MarkersCheckBox = uicheckbox(app.DataTypesPanel);
            app.MarkersCheckBox.Text = 'Markers';
            app.JointAnglesCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointAnglesCheckBox.Text = 'Joint Angles';
            app.JointVelocitiesCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointVelocitiesCheckBox.Text = 'Joint Velocities';
            app.JointMomentCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointMomentCheckBox.Text = 'Joint Moment';
            app.JointForceCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointForceCheckBox.Text = 'Joint Force';
            app.JointPowersCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointPowersCheckBox.Text = 'Joint Powers';
            app.EventsCheckBox = uicheckbox(app.DataTypesPanel);
            app.EventsCheckBox.Text = 'Events';
            app.SubjectDetailsCheckBox = uicheckbox(app.DataTypesPanel);
            app.SubjectDetailsCheckBox.Text = 'Subject Details';
            app.StructureNameLabel = uilabel(app.UIFigure);
            app.StructureNameLabel.Text = '1) Structure Name';
            app.StructureNameEditField = uieditfield(app.UIFigure, 'text');
            app.MakeStructureButton = uibutton(app.UIFigure, 'push');
            app.MakeStructureButton.ButtonPushedFcn = createCallbackFcn(app, @MakeStructure, true);
            app.MakeStructureButton.Text = '6) Make Structure';
            app.SelectTrialsButton = uibutton(app.UIFigure, 'push');
            app.SelectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrialsButtonPushed, true);
            app.SelectTrialsButton.Text = '2) Select Trials';
            app.SelectSaveTargetButton = uibutton(app.UIFigure, 'push');
            app.SelectSaveTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveTargetButtonPushed, true);
            app.SelectSaveTargetButton.Text = '3) Select Save Target';
            app.SelectDataTypesLabel = uilabel(app.UIFigure);
            app.SelectDataTypesLabel.Text = {'4) Select Data Types'; ''};
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ViconDataExtractionforFRBLabel = uilabel(app.UIFigure);
            app.ViconDataExtractionforFRBLabel.Text = 'Vicon Data Extraction for FRB';
            app.CreatorEmmaReznickUpdated022023Label = uilabel(app.UIFigure);
            app.CreatorEmmaReznickUpdated022023Label.Text = {'Creator: Emma Reznick'; 'Updated: 02/2023'};
            app.ConnecttoViconButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoViconButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoViconButtonPushed, true);
            app.ConnecttoViconButton.Text = '5) Connect to Vicon';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ViconLamp = uilamp(app.UIFigure);
            app.ExperimentSpecificEventEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentSpecificEventEditFieldLabel.Text = 'Experiment Specific Event';
            app.ExperimentSpecificEventEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViconExtraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end